classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PerformanceTab            matlab.ui.container.Tab
        BroomAnglePanel           matlab.ui.container.Panel
        Label_6                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        MinimumLabel_13           matlab.ui.control.Label
        MaximumLabel_13           matlab.ui.control.Label
        AverageLabel_10           matlab.ui.control.Label
        SweepingAnglePanel        matlab.ui.container.Panel
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        MinimumLabel_12           matlab.ui.control.Label
        MaximumLabel_12           matlab.ui.control.Label
        AverageLabel_9            matlab.ui.control.Label
        SweepingForcePanel        matlab.ui.container.Panel
        kgLabel_3                 matlab.ui.control.Label
        kgLabel_2                 matlab.ui.control.Label
        kgLabel                   matlab.ui.control.Label
        MinimumLabel_11           matlab.ui.control.Label
        MaximumLabel_11           matlab.ui.control.Label
        AverageLabel_8            matlab.ui.control.Label
        SweepingFrequencyPanel    matlab.ui.container.Panel
        HzLabel_3                 matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        HzLabel                   matlab.ui.control.Label
        MinimumLabel_8            matlab.ui.control.Label
        MaximumLabel_8            matlab.ui.control.Label
        AverageLabel_5            matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        PerformanceandTrainingGoalsPanel  matlab.ui.container.Panel
        UpdateButton              matlab.ui.control.Button
        ToleranceEditField        matlab.ui.control.EditField
        ToleranceLabel            matlab.ui.control.Label
        GoalEditField             matlab.ui.control.EditField
        GoalLabel                 matlab.ui.control.Label
        MetricDropDown            matlab.ui.control.DropDown
        MetricLabel               matlab.ui.control.Label
        SweepingFrequencyTab      matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        SweepingForceTab          matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        SweepingAngleTab_2        matlab.ui.container.Tab
        UIAxes_3                  matlab.ui.control.UIAxes
        BroomAngleTab             matlab.ui.container.Tab
        UIAxes_4                  matlab.ui.control.UIAxes
        DeviceStatusPanel         matlab.ui.container.Panel
        RecordButton              matlab.ui.control.Button
        RecordingStatusLamp       matlab.ui.control.Lamp
        RecordingStatusLampLabel  matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        ConnectionStatusLamp      matlab.ui.control.Lamp
        ConnectionStatusLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % BLE
        bleConnection = "";
        bleStatusTimer;
        bleStatus = 0;

        % App
        appRecordTimer;
        appRecordCharacteristic;
        appRecordService = "220bb2c3-7666-4543-9369-9300d056b016";
        appRecordBool = "1f41e770-b85d-4b30-a90e-1cb4c5ac7f88";
        appRecordStatus = 0;

        % Broom
        broomRecordCharacteristic;
        broomRecordService = "71c20cf2-51bb-4025-9d65-2d76bde6ee83";
        broomRecordBool = "71c20cf2-51bb-4025-9d65-2d76bde6ee83";
        broomRecordStatus = 0;

        % Sweeping frequency
        sweepingFrequencyCharacteristic;
        sweepingFrequencyService = "e3c6fa62-225f-4ed4-8b7b-e836100526df";
        sweepingFrequencyUnsignedShort = "6460a455-3b32-4454-8532-2add24c1a655";
        freqData;
        freqTime;

        % Sweeping force
        sweepingForceCharacteristic;
        sweepingForceService = "8fdf3889-5c69-4e00-a8a2-2f8cbb20c94c";
        sweepingForceUnsignedShort = "43c8c357-2278-48e5-ba17-f9709328924e";
        forceData;
        forceTime;

        % Sweeping angle
        sweepingAngleCharacteristic;
        sweepingAngleService = "374b7b46-591a-4a40-9b26-0f19d76ad45b";
        sweepingAngleUnsignedShort = "dc6fa209-5d45-438c-9776-5e4c78babd91";
        sweepingAngleData;
        sweepingAngleTime;

        % Broom angle
        broomAngleCharacteristic;
        broomAngleService = "3abdbea2-e47c-431b-8113-082b63a45bea";
        broomAngleUnsignedShort = "9f6b0b4f-79e4-4fa9-85de-e78fc2f0eded";
        broomAngleData;
        broomAngleTime;

        % Metric
        currentMetricCharacteristic;
        currentMetricService = "f6ffe3a6-407c-40d8-902c-042b931ed5a5";
        currentMetricUnsignedChar = "e0abaa08-e4c0-4fe7-93bc-a2edede13873";
        currentMetric = 0;

        % Goal
        currentGoalCharacteristic;
        currentGoalService = "a1b44e19-e75d-4033-8230-b7043e8eb8ff";
        currentGoalUnsignedShort = "95501fe3-4cc2-4589-807d-4b4cef4403c9";
        currentGoal = 0;

        % Tolerance
        currentToleranceCharacteristic;
        currentToleranceService = "710b975a-c67d-4f41-b6a1-a19980ee7cf0";
        currentToleranceUnsignedShort = "10cdd090-a68c-4ea6-96ad-aadf9c79eb4b";
        currentTolerance = 0;
    end
    
    methods (Access = private)
        %% Status functions
        function bleConnectionStatus(app)
            if (app.bleConnection.Connected())
                app.ConnectionStatusLamp.Color = [0 1 0];
            else
                app.ConnectionStatusLamp.Color = [1 0 0];
                app.bleConnection = "";
                app.appRecordCharacteristic = "";
                app.broomRecordCharacteristic = "";
                app.sweepingFrequencyCharacteristic = "";
                app.sweepingForceCharacteristic = "";
                app.sweepingAngleCharacteristic = "";
                stop(app.bleStatusTimer);
                
                app.RecordingStatusLamp.Color = [1 0 0];
                app.appRecordStatus = 0;
                app.broomRecordStatus = 0;
                app.RecordButton.Text = "Record";
                stop(app.appRecordTimer);
            end
        end
        function recordStatus(app)
            app.broomRecordStatus = read(app.broomRecordCharacteristic);
            if ((app.appRecordStatus == 1) && (app.broomRecordStatus == 1))
                if (app.currentMetric == 0)
                    readSweepingFrequency(app.sweepingFrequencyCharacteristic);
                elseif (app.currentMetric == 1)
                    readSweepingForce(app.sweepingForceCharacteristic);
                elseif (app.currentMetric == 2)
                    readSweepingAngle(app.sweepingAngleCharacteristic);
                elseif (app.currentMetric == 3)
%                     readBroomAngle(app.broomAngleCharacteristic);
                end
            elseif ((app.appRecordStatus == 0) || (app.broomRecordStatus == 0))
                app.RecordingStatusLamp.Color = [1 0 0];
                app.RecordButton.Text = "Record";
                stop(app.appRecordTimer);
            end
        end
        %% Plot functions
        function plotSweepingFrequency(app)
            app.freqData = evalin('base', 'sweepingFreq');
            app.freqTime = linspace(0, app.appRecordTimer.TasksExecuted * app.appRecordTimer.AveragePeriod, length(app.freqData));

            app.UIAxes.YLim = [0, (max(app.freqData) + 0.2)];
            plot(app.UIAxes, app.freqTime, app.freqData);
            drawnow limitrate;
            disp("Plotting sweeping frequency...");
        end
        function plotSweepingForce(app)
            app.forceData = evalin('base', 'sweepingForce');
            app.forceTime = linspace(0, app.appRecordTimer.TasksExecuted * app.appRecordTimer.AveragePeriod, length(app.forceData));

            app.UIAxes_2.YLim = [0, (max(app.forceData) + 20)];
            plot(app.UIAxes_2, app.forceTime, app.forceData);
            drawnow limitrate;
            disp("Plotting sweeping force...");
        end
        function plotSweepingAngle(app)
            app.sweepingAngleData = evalin('base', 'sweepingAngle');
            app.sweepingAngleTime = linspace(0, app.appRecordTimer.TasksExecuted * app.appRecordTimer.AveragePeriod, length(app.sweepingAngleData));

            app.UIAxes_3.YLim = [0, (max(app.sweepingAngleData) + 20)];
            plot(app.UIAxes_3, app.sweepingAngleTime, app.sweepingAngleData);
            drawnow limitrate;
            disp("Plotting sweeping angle...");
        end
        function plotBroomAngle(app)
            app.broomAngleData = evalin('base', 'broomAngle');
            app.broomAngleTime = linspace(0, app.appRecordTimer.TasksExecuted * app.appRecordTimer.AveragePeriod, length(app.broomAngleData));

            app.UIAxes_4.YLim = [0, (max(app.broomAngleData) + 20)];
            plot(app.UIAxes_4, app.broomAngleTime, app.broomAngleData);
            drawnow limitrate;
            disp("Plotting broom angle...");
        end
        %% Summary functions
        function updateSweepingFrequencySummary(app)
            app.HzLabel.Text = sprintf('%.2f', sum(app.freqData) / length(app.freqData)) + " Hz";
            app.HzLabel_2.Text = sprintf('%.2f', max(app.freqData)) + " Hz";
            app.HzLabel_3.Text = sprintf('%.2f', min(app.freqData)) + " Hz";
        end
        function updateSweepingForceSummary(app)
            app.kgLabel.Text = sprintf('%.2f', sum(app.forceData) / length(app.forceData)) + " kg";
            app.kgLabel_2.Text = sprintf('%.2f', max(app.forceData)) + " kg";
            app.kgLabel_3.Text = sprintf('%.2f', min(app.forceData)) + " kg";
        end
        function updateSweepingAngleSummary(app)
            app.Label.Text = sprintf('%.2f', sum(app.sweepingAngleData) / length(app.sweepingAngleData)) + "°";
            app.Label_2.Text = sprintf('%.2f', max(app.sweepingAngleData)) + "°";
            app.Label_3.Text = sprintf('%.2f', min(app.sweepingAngleData)) + "°";
        end
        function updateBroomgAngleSummary(app)
            app.Label_4.Text = sprintf('%.2f', sum(app.broomAngleData) / length(app.broomAngleData)) + "°";
            app.Label_5.Text = sprintf('%.2f', max(app.broomAngleData)) + "°";
            app.Label_6.Text = sprintf('%.2f', min(app.broomAngleData)) + "°";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             clear sweepingFrequencyCallback;
%             clear sweepingForceCallback;
%             clear sweepingAngleCallback;
%             clear broomAngleCallback;
%             clear sweepingFreq;
%             clear sweepingForce;
%             clear sweepingAngle;
%             clear broomAngle;
            
            app.bleStatusTimer = timer('Period', 1, 'ExecutionMode', 'fixedDelay', 'BusyMode', 'drop');
            app.bleStatusTimer.TimerFcn = @(~, ~)bleConnectionStatus(app);
            app.ConnectionStatusLamp.Color = [1 0 0];

            app.appRecordTimer = timer('Period', 0.005, 'ExecutionMode', 'fixedDelay', 'BusyMode', 'drop');
            app.appRecordTimer.TimerFcn = @(~, ~)recordStatus(app);
            app.RecordingStatusLamp.Color = [1 0 0];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (isequal(app.bleStatusTimer.Running(), 'off'))
                app.bleConnection = ble("0B23B1767498");
                start(app.bleStatusTimer);

                app.appRecordCharacteristic = characteristic(app.bleConnection, app.appRecordService, app.appRecordBool);
                app.broomRecordCharacteristic = characteristic(app.bleConnection, app.broomRecordService, app.broomRecordBool);
                app.sweepingFrequencyCharacteristic = characteristic(app.bleConnection, app.sweepingFrequencyService, app.sweepingFrequencyUnsignedShort);
                app.sweepingForceCharacteristic = characteristic(app.bleConnection, app.sweepingForceService, app.sweepingForceUnsignedShort);
                app.sweepingAngleCharacteristic = characteristic(app.bleConnection, app.sweepingAngleService, app.sweepingAngleUnsignedShort);
                app.broomAngleCharacteristic = characteristic(app.bleConnection, app.broomAngleService, app.broomAngleUnsignedShort);
                app.currentMetricCharacteristic = characteristic(app.bleConnection, app.currentMetricService, app.currentMetricUnsignedChar);
                app.currentGoalCharacteristic = characteristic(app.bleConnection, app.currentGoalService, app.currentGoalUnsignedShort);
                app.currentToleranceCharacteristic = characteristic(app.bleConnection, app.currentToleranceService, app.currentToleranceUnsignedShort);
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if ((app.appRecordStatus == 0) && ~isequal(app.bleConnection, ""))
                app.appRecordStatus = 1;
                write(app.appRecordCharacteristic, 1);

                app.RecordingStatusLamp.Color = [0 1 0];
                app.RecordButton.Text = "Stop Recording";
                start(app.appRecordTimer);
            elseif ((app.appRecordStatus == 1) || isequal(app.bleConnection, ""))
                pause(5);
                if (app.currentMetric == 0)
                    plotSweepingFrequency(app);
                    updateSweepingFrequencySummary(app);
                elseif (app.currentMetric == 1)
                    plotSweepingForce(app);
                    updateSweepingForceSummary(app);
                elseif (app.currentMetric == 2)
                    plotSweepingAngle(app);
                    updateSweepingAngleSummary(app);
                elseif (app.currentMetric == 3)
                    plotBroomAngle(app);
                    updateBroomgAngleSummary(app);
                end

                app.RecordingStatusLamp.Color = [1 0 0];
                app.RecordButton.Text = "Record";
                stop(app.appRecordTimer);

                app.appRecordStatus = 0;
                write(app.appRecordCharacteristic, 0);

%                 clear sweepingFrequencyCallback;
%                 clear sweepingForceCallback;
%                 clear sweepingAngleCallback;
%                 clear broomAngleCallback;
%                 clear sweepingFreq;
%                 clear sweepingForce;
%                 clear sweepingAngle;
%                 clear broomAngle;
                disp("Done");
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if ((app.appRecordStatus == 0) && ~isequal(app.bleConnection, ""))
                if (isequal(app.MetricDropDown.Value, app.MetricDropDown.Items{1}))
                    app.currentMetric = 0;
                elseif (isequal(app.MetricDropDown.Value, app.MetricDropDown.Items{2}))
                    app.currentMetric = 1;
                elseif (isequal(app.MetricDropDown.Value, app.MetricDropDown.Items{3}))
                    app.currentMetric = 2;
                elseif (isequal(app.MetricDropDown.Value, app.MetricDropDown.Items{4}))
                    app.currentMetric = 3;
                end

                write(app.currentMetricCharacteristic, app.currentMetric);

                app.currentGoal = convertCharsToStrings(app.GoalEditField.Value);
                app.currentGoal = str2double(app.currentGoal);
                write(app.currentGoalCharacteristic, (app.currentGoal * 100), 'uint16')
                
                app.currentTolerance = convertCharsToStrings(app.ToleranceEditField.Value);
                app.currentTolerance = str2double(app.currentTolerance);
                write(app.currentToleranceCharacteristic, (app.currentTolerance * 100), 'uint16');

                disp("Updated");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeviceStatusPanel = uipanel(app.UIFigure);
            app.DeviceStatusPanel.Title = 'Device Status';
            app.ConnectionStatusLabel = uilabel(app.DeviceStatusPanel);
            app.ConnectionStatusLabel.Text = 'Connection Status:';
            app.ConnectionStatusLamp = uilamp(app.DeviceStatusPanel);
            app.ConnectButton = uibutton(app.DeviceStatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RecordingStatusLampLabel = uilabel(app.DeviceStatusPanel);
            app.RecordingStatusLampLabel.Text = 'Recording Status:';
            app.RecordingStatusLamp = uilamp(app.DeviceStatusPanel);
            app.RecordButton = uibutton(app.DeviceStatusPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceandTrainingGoalsPanel = uipanel(app.PerformanceTab);
            app.PerformanceandTrainingGoalsPanel.Title = 'Performance and Training Goals';
            app.MetricLabel = uilabel(app.PerformanceandTrainingGoalsPanel);
            app.MetricLabel.Text = 'Metric:';
            app.MetricDropDown = uidropdown(app.PerformanceandTrainingGoalsPanel);
            app.MetricDropDown.Items = {'Sweeping Frequency', 'Sweeping Force', 'Sweeping Angle', 'Broom Angle'};
            app.MetricDropDown.Value = 'Sweeping Frequency';
            app.GoalLabel = uilabel(app.PerformanceandTrainingGoalsPanel);
            app.GoalLabel.Text = 'Goal:';
            app.GoalEditField = uieditfield(app.PerformanceandTrainingGoalsPanel, 'text');
            app.ToleranceLabel = uilabel(app.PerformanceandTrainingGoalsPanel);
            app.ToleranceLabel.Text = 'Tolerance (±):';
            app.ToleranceEditField = uieditfield(app.PerformanceandTrainingGoalsPanel, 'text');
            app.UpdateButton = uibutton(app.PerformanceandTrainingGoalsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Image = uiimage(app.PerformanceTab);
            app.Image.ImageSource = 'western_eng-removebg-preview.png';
            app.Image2 = uiimage(app.PerformanceTab);
            app.Image2.ImageSource = 'goldline.png';
            app.SweepingFrequencyPanel = uipanel(app.PerformanceTab);
            app.SweepingFrequencyPanel.Title = 'Sweeping Frequency';
            app.AverageLabel_5 = uilabel(app.SweepingFrequencyPanel);
            app.AverageLabel_5.Text = 'Average:';
            app.MaximumLabel_8 = uilabel(app.SweepingFrequencyPanel);
            app.MaximumLabel_8.Text = 'Maximum:';
            app.MinimumLabel_8 = uilabel(app.SweepingFrequencyPanel);
            app.MinimumLabel_8.Text = 'Minimum:';
            app.HzLabel = uilabel(app.SweepingFrequencyPanel);
            app.HzLabel.Text = '0 Hz';
            app.HzLabel_2 = uilabel(app.SweepingFrequencyPanel);
            app.HzLabel_2.Text = '0 Hz';
            app.HzLabel_3 = uilabel(app.SweepingFrequencyPanel);
            app.HzLabel_3.Text = '0 Hz';
            app.SweepingForcePanel = uipanel(app.PerformanceTab);
            app.SweepingForcePanel.Title = 'Sweeping Force';
            app.AverageLabel_8 = uilabel(app.SweepingForcePanel);
            app.AverageLabel_8.Text = 'Average:';
            app.MaximumLabel_11 = uilabel(app.SweepingForcePanel);
            app.MaximumLabel_11.Text = 'Maximum:';
            app.MinimumLabel_11 = uilabel(app.SweepingForcePanel);
            app.MinimumLabel_11.Text = 'Minimum:';
            app.kgLabel = uilabel(app.SweepingForcePanel);
            app.kgLabel.Text = '0 kg';
            app.kgLabel_2 = uilabel(app.SweepingForcePanel);
            app.kgLabel_2.Text = '0 kg';
            app.kgLabel_3 = uilabel(app.SweepingForcePanel);
            app.kgLabel_3.Text = '0 kg';
            app.SweepingAnglePanel = uipanel(app.PerformanceTab);
            app.SweepingAnglePanel.Title = 'Sweeping Angle';
            app.AverageLabel_9 = uilabel(app.SweepingAnglePanel);
            app.AverageLabel_9.Text = 'Average:';
            app.MaximumLabel_12 = uilabel(app.SweepingAnglePanel);
            app.MaximumLabel_12.Text = 'Maximum:';
            app.MinimumLabel_12 = uilabel(app.SweepingAnglePanel);
            app.MinimumLabel_12.Text = 'Minimum:';
            app.Label = uilabel(app.SweepingAnglePanel);
            app.Label.Text = '0°';
            app.Label_2 = uilabel(app.SweepingAnglePanel);
            app.Label_2.Text = '0°';
            app.Label_3 = uilabel(app.SweepingAnglePanel);
            app.Label_3.Text = '0°';
            app.BroomAnglePanel = uipanel(app.PerformanceTab);
            app.BroomAnglePanel.Title = 'Broom Angle';
            app.AverageLabel_10 = uilabel(app.BroomAnglePanel);
            app.AverageLabel_10.Text = 'Average:';
            app.MaximumLabel_13 = uilabel(app.BroomAnglePanel);
            app.MaximumLabel_13.Text = 'Maximum:';
            app.MinimumLabel_13 = uilabel(app.BroomAnglePanel);
            app.MinimumLabel_13.Text = 'Minimum:';
            app.Label_4 = uilabel(app.BroomAnglePanel);
            app.Label_4.Text = '0°';
            app.Label_5 = uilabel(app.BroomAnglePanel);
            app.Label_5.Text = '0°';
            app.Label_6 = uilabel(app.BroomAnglePanel);
            app.Label_6.Text = '0°';
            app.SweepingFrequencyTab = uitab(app.TabGroup);
            app.SweepingFrequencyTab.Title = 'Sweeping Frequency';
            app.UIAxes = uiaxes(app.SweepingFrequencyTab);
            title(app.UIAxes, 'Sweeping Frequency vs. Time')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Sweeping Frequency (Hz)')
            zlabel(app.UIAxes, 'Z')
            app.SweepingForceTab = uitab(app.TabGroup);
            app.SweepingForceTab.Title = 'Sweeping Force';
            app.UIAxes_2 = uiaxes(app.SweepingForceTab);
            title(app.UIAxes_2, 'Sweeping Force vs. Time')
            xlabel(app.UIAxes_2, 'Time (seconds)')
            ylabel(app.UIAxes_2, 'Sweeping Force (kg)')
            zlabel(app.UIAxes_2, 'Z')
            app.SweepingAngleTab_2 = uitab(app.TabGroup);
            app.SweepingAngleTab_2.Title = 'Sweeping Angle';
            app.UIAxes_3 = uiaxes(app.SweepingAngleTab_2);
            title(app.UIAxes_3, 'Sweeping Angle vs. Time')
            xlabel(app.UIAxes_3, 'Time (seconds)')
            ylabel(app.UIAxes_3, 'Sweeping Angle (deg)')
            zlabel(app.UIAxes_3, 'Z')
            app.BroomAngleTab = uitab(app.TabGroup);
            app.BroomAngleTab.Title = 'Broom Angle';
            app.UIAxes_4 = uiaxes(app.BroomAngleTab);
            title(app.UIAxes_4, 'Broom Angle vs. Time')
            xlabel(app.UIAxes_4, 'Time (seconds)')
            ylabel(app.UIAxes_4, 'Broom Angle (deg)')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end